classdef TrainOptsDlg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        dlgTrainOpts                   matlab.ui.Figure
        labelBaseNet                   matlab.ui.control.Label
        dropdownBaseNet                matlab.ui.control.DropDown
        BaseNetworkLabel               matlab.ui.control.Label
        buttonOK                       matlab.ui.control.Button
        panelVariables                 matlab.ui.container.Panel
        labelUpdateDetsInfo            matlab.ui.control.Label
        checkboxUpdateDets             matlab.ui.control.CheckBox
        labelExpECInfo                 matlab.ui.control.Label
        checkboxOverwriteEC            matlab.ui.control.CheckBox
        checkboxExpEC                  matlab.ui.control.CheckBox
        labelModelInfo                 matlab.ui.control.Label
        checkboxModel                  matlab.ui.control.CheckBox
        labelSelectItemstoSave         matlab.ui.control.Label
        buttonDefaults                 matlab.ui.control.Button
        panelImages                    matlab.ui.container.Panel
        labelImgSize                   matlab.ui.control.Label
        editfieldImgSize               matlab.ui.control.NumericEditField
        ImageSizesquareEditFieldLabel  matlab.ui.control.Label
        imgtSNE                        matlab.ui.control.Image
        checkboxtSNE                   matlab.ui.control.CheckBox
        imgCentCont                    matlab.ui.control.Image
        imgClosestCalls                matlab.ui.control.Image
        checkboxCentCont               matlab.ui.control.CheckBox
        checkboxClosestCalls           matlab.ui.control.CheckBox
        imgSilhouettes                 matlab.ui.control.Image
        checkboxSilhouettes            matlab.ui.control.CheckBox
        imgClustImgs                   matlab.ui.control.Image
        checkboxClustImgs              matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        CallingApp % Parent app object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, strPrompt, basemodel, imgdim)
            % Link to parent app
            app.CallingApp = mainapp;
            app.labelBaseNet.Text = strPrompt;
            if ~strcmp(basemodel,'')
                app.dropdownBaseNet.Items = {'Tiny YOLO v4 COCO', 'CSP-DarkNet-53', 'ResNet-50 (pre-trained)', 'ResNet-50 (blank)'};
                app.dropdownBaseNet.Value = basemodel;
            end
        end

        % Callback function: not associated with a component
        function buttonSelectDeselect_Callback(app, event)
            % If any checkboxes unchecked, check them all
            if ~app.checkboxClustImgs.Value || ~app.checkboxSilhouettes.Value || ...
                    ~app.checkboxClosestCalls.Value || ~app.checkboxCentCont.Value || ~app.checkboxtSNE.Value || ...
                    ~app.checkboxModel.Value || ~app.checkboxExpEC.Value || ...
                    ~app.checkboxOverwriteEC.Value || ~app.checkboxUpdateDets.Value
                app.checkboxClustImgs.Value = true;
                app.checkboxSilhouettes.Value = true;
                app.checkboxClosestCalls.Value = true;
                app.checkboxCentCont.Value = true;
                app.checkboxtSNE.Value = true;
                app.checkboxModel.Value = true;
                app.checkboxExpEC.Value = true;
                app.checkboxOverwriteEC.Value = true;
                app.checkboxUpdateDets.Value = true;
            % Otherwise, uncheck them all
            else
                app.checkboxClustImgs.Value = false;
                app.checkboxSilhouettes.Value = false;
                app.checkboxClosestCalls.Value = false;
                app.checkboxCentCont.Value = false;
                app.checkboxtSNE.Value = false;
                app.checkboxModel.Value = false;
                app.checkboxExpEC.Value = false;
                app.checkboxOverwriteEC.Value = false;
                app.checkboxUpdateDets.Value = false;
            end
        end

        % Button pushed function: buttonOK
        function buttonOK_Callback(app, event)
            % Pass values to parent app
            app.CallingApp.strUnsupSaveLoc = app.textareaSaveLocation.Value{:};
            app.CallingApp.bClustImg = app.checkboxClustImgs.Value;
            app.CallingApp.bSilh = app.checkboxSilhouettes.Value;
            app.CallingApp.bClosest = app.checkboxClosestCalls.Value;
            app.CallingApp.bContours = app.checkboxCentCont.Value;
            app.CallingApp.btSNE = app.checkboxtSNE.Value;
            app.CallingApp.bModel = app.checkboxModel.Value;
            app.CallingApp.bEEC = app.checkboxExpEC.Value;
            app.CallingApp.bECOverwrite = app.checkboxOverwriteEC.Value;
            app.CallingApp.bUpdateDets = app.checkboxUpdateDets.Value;
            
            % Delete Save dialog
            delete(app)
        end

        % Close request function: dlgTrainOpts
        function dlgTrainOptsSaveCloseRequest(app, event)
            % Delete Save dialog
            delete(app)
        end

        % Button pushed function: buttonDefaults
        function buttonDefaults_Callback(app, event)
            
        end

        % Value changed function: dropdownBaseNet
        function dropdownBaseNetValueChanged(app, event)
            % Reset everything that depends on what base model is
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.dlgTrainOpts = uifigure('Visible', 'off');
            app.dlgTrainOpts.Name = 'MATLAB App';
            app.dlgTrainOpts.CloseRequestFcn = createCallbackFcn(app, @dlgTrainOptsSaveCloseRequest, true);
            app.panelImages = uipanel(app.dlgTrainOpts);
            app.panelImages.Title = 'Customizable Options';
            app.checkboxClustImgs = uicheckbox(app.panelImages);
            app.checkboxClustImgs.Text = 'Cluster Images';
            app.checkboxClustImgs.Value = true;
            app.imgClustImgs = uiimage(app.panelImages);
            app.imgClustImgs.ImageSource = 'Ex_ClustImg.png';
            app.checkboxSilhouettes = uicheckbox(app.panelImages);
            app.checkboxSilhouettes.Text = 'Silhouette Plot';
            app.checkboxSilhouettes.Value = true;
            app.imgSilhouettes = uiimage(app.panelImages);
            app.imgSilhouettes.ImageSource = 'Ex_Silh.png';
            app.checkboxClosestCalls = uicheckbox(app.panelImages);
            app.checkboxClosestCalls.Text = 'Closest Calls to Centroids';
            app.checkboxClosestCalls.Value = true;
            app.checkboxCentCont = uicheckbox(app.panelImages);
            app.checkboxCentCont.Text = 'Centroid Contours';
            app.checkboxCentCont.Value = true;
            app.imgClosestCalls = uiimage(app.panelImages);
            app.imgClosestCalls.ImageSource = 'Ex_ClosestCall.png';
            app.imgCentCont = uiimage(app.panelImages);
            app.imgCentCont.ImageSource = 'Ex_CentConts.png';
            app.checkboxtSNE = uicheckbox(app.panelImages);
            app.checkboxtSNE.Text = 't-SNE';
            app.checkboxtSNE.Value = true;
            app.imgtSNE = uiimage(app.panelImages);
            app.imgtSNE.ImageSource = fullfile(pathToMLAPP, 'UnsupClustSaveDlg', 'Ex_tSNE.png');
            app.ImageSizesquareEditFieldLabel = uilabel(app.panelImages);
            app.ImageSizesquareEditFieldLabel.Enable = 'off';
            app.ImageSizesquareEditFieldLabel.Text = 'Image Size (square)';
            app.editfieldImgSize = uieditfield(app.panelImages, 'numeric');
            app.editfieldImgSize.Limits = [0 Inf];
            app.editfieldImgSize.Enable = 'off';
            app.labelImgSize = uilabel(app.panelImages);
            app.labelImgSize.Text = 'If GPU crashes (out of memory error), you may need to reduce the image size.';
            app.buttonDefaults = uibutton(app.dlgTrainOpts, 'push');
            app.buttonDefaults.ButtonPushedFcn = createCallbackFcn(app, @buttonDefaults_Callback, true);
            app.buttonDefaults.Enable = 'off';
            app.buttonDefaults.Text = 'Run Defaults';
            app.labelSelectItemstoSave = uilabel(app.dlgTrainOpts);
            app.labelSelectItemstoSave.Text = 'Select Training Options';
            app.panelVariables = uipanel(app.dlgTrainOpts);
            app.panelVariables.Title = 'Variables (.mat)';
            app.checkboxModel = uicheckbox(app.panelVariables);
            app.checkboxModel.Text = 'KMeans Model';
            app.checkboxModel.Value = true;
            app.labelModelInfo = uilabel(app.panelVariables);
            app.labelModelInfo.Text = {'Contains model information, including:'; '     Centroid Matrix'; '     Cluster Labels'; '     Model Weights'; '     # of Contour Points'; '     Parson''s Code Resolution'};
            app.checkboxExpEC = uicheckbox(app.panelVariables);
            app.checkboxExpEC.Text = 'Expanded Extracted Contours';
            app.checkboxExpEC.Value = true;
            app.checkboxOverwriteEC = uicheckbox(app.panelVariables);
            app.checkboxOverwriteEC.Text = 'Overwrite Existing?';
            app.checkboxOverwriteEC.Value = true;
            app.labelExpECInfo = uilabel(app.panelVariables);
            app.labelExpECInfo.Text = {'Contains additional calculated information for each'; 'detection, including:'; '     Cluster Indices'; '     Euclidean Distance to Centroid'; '     [# of Contour Pts]-Contour'; '     # of Inflection Points'; '     Silhouette Value'};
            app.checkboxUpdateDets = uicheckbox(app.panelVariables);
            app.checkboxUpdateDets.Text = 'Update "ClustCat" in Detections';
            app.checkboxUpdateDets.Value = true;
            app.labelUpdateDetsInfo = uilabel(app.panelVariables);
            app.labelUpdateDetsInfo.Text = {'Will update the "ClustCat" variable in the active'; 'Detections.mat file(s) with the cluster assignments for this'; 'clustering run (WARNING: This will overwrite any previous '; 'clustering assignments already saved as "ClustCat". This'; 'information does not impact any future clustering runs.)'};
            app.buttonOK = uibutton(app.dlgTrainOpts, 'push');
            app.buttonOK.ButtonPushedFcn = createCallbackFcn(app, @buttonOK_Callback, true);
            app.buttonOK.Text = 'OK';
            app.BaseNetworkLabel = uilabel(app.dlgTrainOpts);
            app.BaseNetworkLabel.Text = 'Base Network';
            app.dropdownBaseNet = uidropdown(app.dlgTrainOpts);
            app.dropdownBaseNet.Items = {'Please Select', 'Tiny YOLO v4 COCO', 'CSP-DarkNet-53', 'ResNet-50 (pre-trained)', 'ResNet-50 (blank)'};
            app.dropdownBaseNet.ValueChangedFcn = createCallbackFcn(app, @dropdownBaseNetValueChanged, true);
            app.dropdownBaseNet.Value = 'Please Select';
            app.labelBaseNet = uilabel(app.dlgTrainOpts);
            app.labelBaseNet.Text = 'First, choose a base network:';
            % Show the figure after all components are created
            app.dlgTrainOpts.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrainOptsDlg(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.dlgTrainOpts)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.dlgTrainOpts)
        end
    end
end